classdef spatialbox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_gradpiv           matlab.ui.Figure
        File                     matlab.ui.container.Menu
        loadVec                  matlab.ui.container.Menu
        loadMat                  matlab.ui.container.Menu
        loadTXT                  matlab.ui.container.Menu
        export2figure            matlab.ui.container.Menu
        export2MAT               matlab.ui.container.Menu
        exit                     matlab.ui.container.Menu
        Data                     matlab.ui.container.Menu
        Filter                   matlab.ui.container.Menu
        Global                   matlab.ui.container.Menu
        Median                   matlab.ui.container.Menu
        interpolate              matlab.ui.container.Menu
        Crop                     matlab.ui.container.Menu
        axes1                    matlab.ui.control.UIAxes
        pushbutton_select        matlab.ui.control.Button
        pushbutton_spatial       matlab.ui.control.Button
        spatial_controls         matlab.ui.container.Panel
        frame8                   matlab.ui.container.Panel
        checkbox_ensemble        matlab.ui.control.CheckBox
        checkbox_fluct           matlab.ui.control.CheckBox
        arrow_controls           matlab.ui.container.Panel
        checkbox_arrow           matlab.ui.control.CheckBox
        checkbox_arrow_color     matlab.ui.control.CheckBox
        edit_arrow_size          matlab.ui.control.EditField
        text_arrow_size          matlab.ui.control.Label
        contour_quantity_panel   matlab.ui.container.Panel
        popupmenu_quantity       matlab.ui.control.DropDown
        contour_panel            matlab.ui.container.Panel
        text_numberofcolors      matlab.ui.control.Label
        edit_numcolors           matlab.ui.control.EditField
        edit_min_clim            matlab.ui.control.EditField
        edit_max_clim            matlab.ui.control.EditField
        checkbox_colorbar        matlab.ui.control.CheckBox
        popupmenu_eachfield      matlab.ui.control.DropDown
        pushbutton_set_clim      matlab.ui.control.Button
        popupmenu_contour_type   matlab.ui.control.DropDown
        checkbox_label           matlab.ui.control.CheckBox
        flow_field_panel         matlab.ui.container.Panel
        pushbutton_previous      matlab.ui.control.Button
        pushbutton_next          matlab.ui.control.Button
        edit_current             matlab.ui.control.EditField
        text5                    matlab.ui.control.Label
        edit_numfields           matlab.ui.control.EditField
        pushbutton_animate       matlab.ui.control.StateButton
        pushbutton_save_movie    matlab.ui.control.StateButton
        select_controls          matlab.ui.container.Panel
        uipanel12                matlab.ui.container.Panel
        pushbutton_profile1      matlab.ui.control.Button
        pushbutton_time          matlab.ui.control.Button
        select_optons_panel      matlab.ui.container.Panel
        pushbutton_selectall     matlab.ui.control.Button
        colpushbutton            matlab.ui.control.Button
        pushbutton_selectreg     matlab.ui.control.Button
        pushbutton_selectpoints  matlab.ui.control.Button
        rowpushbutton            matlab.ui.control.Button
        pushbutton_reset         matlab.ui.control.Button
    end


    properties (Access = private)
        handles % Description
    end
    
    methods (Access = private)
        function filenames = ReadTXTDir(app, dirname, data)
            if nargin < 2
                data = 'txt';
            end
            
            switch data
                case{'_noflt.txt'} % a)
                    direc = dir([dirname,filesep,'*_noflt.txt']);
                case{'_flt.txt'} %b)
                    direc = dir([dirname,filesep,'*_flt.txt']);
                case{'txt'} % c)
                    direc = dir([dirname,filesep,'*.txt']);
                    tmp = struct('name',[]);
                    k = 0;
                    for i=1:length(direc)
                        if length(findstr(direc(i).name,'flt')) < 1
                            k = k + 1;
                            tmp(k).name = direc(i).name;
                        end
                    end
                    direc = tmp;
            end
            
            if ~isempty(direc(1).name) && ~isempty(str2num(direc(1).name(1:length(direc(1).name)-4)))
                for i = 1:length(direc)
                    n(i) = str2num(direc(i).name(1:length(direc(i).name)-4));
                end
                [~,j] = sort(n);
                direc = direc(j);
            end
            
            filenames={};
            [filenames{1:length(direc),1}] = deal(direc.name);
            % filenames = sortrows(filenames);
            return
        end
    
        function axes_main_CreateFcn(app, hObject)
            
            app.axes_main = hObject;
            guidata(hObject, app.handles);
        end
        
        function ed_max_CreateFcn(hObject)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function hh = quiverc(app, varargin)
            % Modified QUIVER() that uses Patches instead of lines and allows
            % to color arrows and have updated colorbar, according to the
            % correct color data mapping. Arrows are of the same type as in quiver plot.
            %
            %   Modified version: (c) Alex Liberzon
            %   $Revision: 1.02 $  $ Date: 05/10/2002 $
            %   See also: HELP QUIVER (skipped here)
            
            
            % Arrow head parameters
            alpha = 0.33; % Size of arrow head relative to the length of the vector
            beta = 0.33;  % Width of the base of the arrow head relative to the length
            autoscale = 1; % Autoscale if ~= 0 then scale by this.
            plotarrows = 1; % Plot arrows
            sym = '';
            
            filled = 0;
            ls = '-';
            ms = '';
            col = '';
            
            nin = nargin;
            % Parse the string inputs
            while isstr(varargin{nin}),
                vv = varargin{nin};
                if ~isempty(vv) & strcmp(lower(vv(1)),'f')
                    filled = 1;
                    nin = nin-1;
                else
                    [l,c,m,msg] = colstyle(vv);
                    if ~isempty(msg),
                        error(sprintf('Unknown option "%s".',vv));
                    end
                    if ~isempty(l), ls = l; end
                    if ~isempty(c), col = c; end
                    if ~isempty(m), ms = m; plotarrows = 0; end
                    if isequal(m,'.'), ms = ''; end % Don't plot '.'
                    nin = nin-1;
                end
            end
            
            error(nargchk(2,6,nin));
            
            % Check numeric input arguments
            if nin<4, % quiver(u,v) or quiver(u,v,s)
                [msg,x,y,u,v] = xyzchk(varargin{1:2});
            else
                [msg,x,y,u,v] = xyzchk(varargin{1:4});
            end
            if ~isempty(msg), error(msg); end
            
            if nin==4 | nin==6, % quiver(u,v,z,s) or quiver(x,y,u,v,z,s)
                autoscale = varargin{nin-1};
                z = varargin{nin};
            end
            
            % Scalar expand u,v
            if prod(size(u))==1, u = u(ones(size(x))); end
            if prod(size(v))==1, v = v(ones(size(u))); end
            
            if autoscale,
                if min(size(x))==1
                    n=sqrt(prod(size(x)));
                    m=n;
                else
                    [m,n]=size(x);
                end
                delx = diff([min(x(:)) max(x(:))])/n;
                dely = diff([min(y(:)) max(y(:))])/m;
                del = delx.^2 + dely.^2;
                if del>0
                    len = sqrt((u.^2 + v.^2)/del);
                    maxlen = max(len(:));
                else
                    maxlen = 0;
                end
            
                if maxlen>0
                    autoscale = autoscale*0.9 / maxlen;
                else
                    autoscale = autoscale*0.9;
                end
                u = u*autoscale; v = v*autoscale;
            end
            
            
            ax = newplot;
            next = lower(get(ax,'NextPlot'));
            hold_state = ishold;
            
            % Make velocity vectors
            x = x(:).'; y = y(:).';
            u = u(:).'; v = v(:).';
            uu = [x;x+u;repmat(NaN,size(u))];
            vv = [y;y+v;repmat(NaN,size(u))];
            
            % Prepare color matrix
            z = [z(:)';z(:)';NaN*z(:)'];
            
            h1 = patch([uu(:),uu(:)],[vv(:),vv(:)], [z(:),z(:)],'Parent',ax,'EdgeColor','Flat','FaceColor','None');
            
            if plotarrows,
                % Make arrow heads and plot them
                hu = [x+u-alpha*(u+beta*(v+eps));x+u; ...
                    x+u-alpha*(u-beta*(v+eps));repmat(NaN,size(u))];
                hv = [y+v-alpha*(v-beta*(u+eps));y+v; ...
                    y+v-alpha*(v+beta*(u+eps));repmat(NaN,size(v))];
                hold on
                % Modify color matrix
                z = [z(1,:); z];
                h2 = patch([hu(:),hu(:)],[hv(:),hv(:)], [z(:),z(:)],'Parent',ax,'EdgeColor','Flat','FaceColor','None');
            
            else
                h2 = [];
            end
            
            if ~isempty(ms), % Plot marker on base
                hu = x; hv = y;
                hold on
                h3 = plot(hu(:),hv(:),[col ms]);
                if filled, set(h3,'markerfacecolor',get(h1,'color')); end
            else
                h3 = [];
            end
            
            if ~hold_state, hold off, view(2); set(ax,'NextPlot',next); end
            
            if nargout > 0, hh = [h1;h2;h3]; end
        end
        
    end
    

    methods (Access = private)

        % Menu selected function: Crop
        function Crop_Callback(app, event)
            % hObject    handle to Crop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            
            
            % axes(app.axes_main);
            app.rect = getrect(app);
            rectangle('position',app.rect,'Curvature',[0 0],'Edgecolor','blue','Linestyle',':');
            app.xind = find(app.x(1,:) > app.rect(1) & app.x(1,:) < (app.rect(1) + app.rect(3)));
            app.yind = find(app.y(:,1) > app.rect(2) & app.y(:,1) < (app.rect(2) + app.rect(4)));
            
            w = whos('app.handles');
            if w.bytes > 60000000
            
                errordlg('Matlab does not know how to release memory, call Mathworks');
                %
                %
                % rmfield(app.handles,{'uf','vf','dudx','dudy','dvdx','dvdy'});
                %
                % save('tmp.mat','app.handles');
                % clear all
                % load('tmp.mat');
                %
                %
                % app.x = app.x(yind,app.xind);
                % app.y = app.y(yind,app.xind);
                % app.u = app.u(yind,app.xind,:);
                % app.v = app.v(yind,app.xind,:);
                %
                % [rows,cols,app.N] = size(app.u);
                %
                % % app.dudx = app.dudx(yind,app.xind,:);
                % % app.dudy = app.dudy(yind,app.xind,:);
                % % app.dvdx = app.dvdx(yind,app.xind,:);
                % % app.dvdy = app.dvdy(yind,app.xind,:);
                % %
                % % app.uf = app.uf(yind,app.xind,:);
                % % app.vf = app.vf(yind,app.xind,:);
                %
                % rmfield(app.handles,{'uf','vf','dudx','dudy','dvdx','dvdy'});
                %
                % app.uf = zeros(rows,cols,app.N);
                % app.vf = zeros(rows,cols,app.N);
                % %
                % for i = 1:app.N
                %     app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                % end
                % %
                % for i = 1:app.N
                %     app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
                % end
                %
                % % Derivatives
                % [app.dudx,app.dudy,app.dvdx,app.dvdy] = ...
                %     deal(zeros(rows,cols,app.N+1));
                % % app.dudy = zeros(rows,cols,app.N+1);
                % % app.dvdx = zeros(rows,cols,app.N+1);
                % % app.dvdy = zeros(rows,cols,app.N+1);
                % %
                % for i = 1:app.N+1
                %     [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                %     [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
                % end
            
            else
                app.x = app.x(app.yind,app.xind);
                app.y = app.y(app.yind,app.xind);
                app.u = app.u(app.yind,app.xind,:);
                app.v = app.v(app.yind,app.xind,:);
                app.dudx = app.dudx(app.yind,app.xind,:);
                app.dudy = app.dudy(app.yind,app.xind,:);
                app.dvdx = app.dvdx(app.yind,app.xind,:);
                app.dvdy = app.dvdy(app.yind,app.xind,:);
            
                app.uf = app.uf(app.yind,app.xind,:);
                app.vf = app.vf(app.yind,app.xind,:);
            
            end
            update_gui(app,[],app.handles);
            guidata(app,app.handles);
        end

        % Menu selected function: Global
        function Global_Callback(app, event)
            % hObject    handle to Global (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            
            
            %%%%%%%%%%%%%%%%%%%
            % Reshape U and V matrices in two-dimensional grid and produce
            % velocity vector in U + i*V form (real and imaginary parts):


            [rows,cols] = size(app.u(:,:,1));
            %     % Remove outlayers - GLOBAL FILTERING
            for i = 1:app.N
                vector = sqrt(app.u(:,:,i).^2 + app.v(:,:,i).^2);
                % index = find(vector > (median(vector) + 6*std(vector)));
                [vector,index,outliers] = deleteoutliers(vector(:),0.05);
                [k,m] = ind2sub([rows,cols],index);
                app.u(k,m,i) = 0;
                app.v(k,m,i) = 0;
            end
            
            % Update data
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end
            
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            update_gui(app,[],app.handles);
            guidata(app,app.handles);
            
            
            update_gui(app,[],app.handles);
            guidata(app,app.handles)
        end

        % Menu selected function: Median
        function Median_Callback(app, event)
            % hObject    handle to Median (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            %
            % Adaptive Local Median filtering
            
            kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];
            [rows,cols] = size(app.u(:,:,1));
            
            for i = 1:app.N
                tmpv = abs(conv2(app.v(:,:,i),kernel,'same'));
                tmpu = abs(conv2(app.u(:,:,i),kernel,'same'));
            
                ind = tmpv~=0 & tmpu~=0;
                lmtv = mean(tmpv(ind)) + 3*std(tmpv(ind));
                lmtu = mean(tmpu(ind)) + 3*std(tmpu(ind));
            
                out = find(tmpu > lmtu  & tmpv > lmtv);
            
                [k,m] = ind2sub([rows,cols],out);
                app.u(k,m,i) = 0;
                app.v(k,m,i) = 0;
            end
            
            % Update data
            
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end
            
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            update_gui(app,[],app.handles);
            guidata(app,app.handles);
            
            
            update_gui(app,[],app.handles);
            guidata(app,app.handles);
        end

        % Value changed function: checkbox_arrow
        function checkbox_arrow_Callback(app, event)
            % arrow on / off callback
            % if arrow - off is checked, hide the arrows.
            if get(app.checkbox_arrow,'Value') == 0
                app.color=0;
                set(app.checkbox_arrow_color,'Enable','off');
                set(app.checkbox_arrow_color,'Value',0);
            else
                set(app.checkbox_arrow_color,'Enable','on');
                delete(get(app.axes_main,'children'));
            end
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: checkbox_arrow_color
        function checkbox_arrow_color_Callback(app, event)
            % Color arrows depends on the app.property, chosen from the list of the
            % avialable quantities
            
            % color / black callback
            if (get(hObject,'Value') == 1)
                app.color = 1;
            else
                % checkbox is not checked-take approriate action
                app.color = 0;
                %     app.color_quiver = get(get(app,'currentaxes'),'children');
                set(app.color_quiver,'Visible','off');
            end
            
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: checkbox_colorbar
        function checkbox_colorbar_Callback(app, event)
            if get(h,'Value') == 1
                app.colorbar_flag = 1;
                guidata(app,app.handles);
                update_gui(app,[],app.handles);
            else
                delete(findobj(app,'Tag','Colorbar'));
                app.colorbar_flag = 0;
                set(app.axes_main,'position',app.axpos);
                guidata(app,app.handles);
            end
        end

        % Value changed function: checkbox_ensemble
        function checkbox_ensemble_Callback(app, event)
            
            % global inst_list mean_list fluct_list fluct_mean_list;
            set (app.arrow_ctrls,'Enable','on');
            if get(app.checkbox_ensemble,'Value') == 1
            
                % No meaning for All fields and All to Display for Ensemble cases
                % However, we do not modify the actual subroutines, in order not to
                % destroy the code there
                set(app.popupmenu_eachfield,'String','Each Field|---|---|Manual');
                if get(app.checkbox_fluct,'Value') == 1
                    set (app.popupmenu_quantity,'String',app.fluct_mean_list);
                    set (app.arrow_ctrls,'Enable','off');
                else
                    set (app.popupmenu_quantity,'String',app.mean_list);
                end
                app.current_index = app.current;
                app.current = app.N + 1;
                set(app.pushbutton_previous,'Enable','off');
                set(app.pushbutton_next,'Enable','off');
                set(app.pushbutton_animate,'Enable','off');
                set(app.pushbutton_save_movie,'Enable','off');
                set(app.edit_current,'Enable','off');
            else
                set(app.popupmenu_eachfield,'String','Each Field|All to Display|All Fields|Manual');
                if (get(app.checkbox_fluct,'Value') == get(app.checkbox_fluct,'Max'))
                    set (app.popupmenu_quantity,'String',app.fluct_list);
                else
                    set (app.popupmenu_quantity,'String',app.inst_list);
                end
                if app.current == app.N + 1;
                    app.current = app.current_index;
                end
                set(app.pushbutton_previous,'Enable','on');
                set(app.pushbutton_next,'Enable','on');
                set(app.pushbutton_animate,'Enable','on');
                set(app.pushbutton_save_movie,'Enable','on');
                set(app.edit_current,'Enable','on');
            end;
            
            set(app.popupmenu_quantity,'Value',1);         % 2104 by Denis
            app.property=[];  app.C=[]; app.CH=[];  %0304 by Denis
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: checkbox_fluct
        function checkbox_fluct_Callback(app, event)
            set (app.arrow_ctrls,'Enable','on');
            if (get(app.checkbox_fluct,'Value') == 1)
                if (get(app.checkbox_ensemble,'Value') == 1)
                    set (app.popupmenu_quantity,'String',app.fluct_mean_list);
                    set (app.arrow_ctrls,'Enable','off');
                else
                    set (app.popupmenu_quantity,'String',app.fluct_list);
                end
            else
                if (get(app.checkbox_ensemble,'Value') == 1)
                    set (app.popupmenu_quantity,'String',app.mean_list);
                else
                    set (app.popupmenu_quantity,'String',app.inst_list);
                end
            end;
            set(app.popupmenu_quantity,'Value',1);     % 2104 by Denis
            app.property    =   [];
            app.C           =   [];
            app.CH          =   [];                        % 0304 by Denis
            
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: checkbox_label
        function checkbox_label_Callback(app, event)
            if (get(h,'Value') == get(h,'Max'))
                if get(app.popupmenu_contour_type,'Value')>1
                    app.labelit = 1;
            
                    clabel(app.C,app.CH);
                    guidata(app,app.handles);
                else
                    set(app.checkbox_label,'Value',0);
                end;
                %update_gui(app,[],app.handles);
            else
                app.labelit = 0;
                guidata(app,app.handles);
                update_gui(app,[],app.handles);
            end
        end

        % Button pushed function: colpushbutton
        function colpushbutton_Callback(app, event)
            % hObject    handle to colpushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            set(app.pushbutton_selectpoints,'Enable','off');
            set(app.pushbutton_selectreg,'Enable','off');
            set(app.rowpushbutton,'Enable','off');
            set(app.pushbutton_selectall,'Enable','off');
            
            set(app.axes_main,'NextPlot','Add');
            limX = xlim;
            limY = ylim;
            leftcolX   = 1;
            bottomrowY = 1;
            
            while 1
            
                [x1,y1, buttonNumber] = ginput(1);
            
                % When the right button is pressed, stop the loop
                if (buttonNumber == 2) | (buttonNumber==3)
                    break;
                end;
                col = fix (( x1 - limX(1,1) )/ app.gridX+0.5  )+1;
                row = fix(( y1 - limY(1,1) )/ app.gridY+0.5  )+1;
                % -------- find the corners of rectangle ----
            
            
                % check for errors ----------------
                if col<1 | col>(fix((limX(1,2)-limX(1,1))/app.gridX)+1) | row<1 | row...
                        >(fix((limY(1,2)-limY(1,1))/app.gridY)+1);
                    guidata(app,app.handles);
                    return;
                end;
                % ---------------------------------
                sizeI = size(app.i,1);
                rightcolX = fix(( limX(1,2)-limX(1,1) )/  app.gridX )+1;
                uprowY    = fix(( limY(1,2)-limY(1,1) )/  app.gridY )+1;
                sizeJ = size(app.j,1);
                numofcols = rightcolX - leftcolX + 1;
                numofrows = uprowY - bottomrowY + 1;
                %
                app.i(sizeI+1:sizeI+numofcols,1) = row;
                app.j(sizeJ+1:sizeJ+numofcols,1) = 1:rightcolX;
                col = 1:rightcolX;
                %
                line(limX(1,1)+(col-1)*...
                    app.gridX,limY(1,1)+(row-1)*app.gridY,'Marker','o','Color','k','MarkerSize',8);
            end;
            
            
            guidata(app,app.handles);
        end

        % Value changed function: edit_arrow_size
        function edit_arrow_size_Callback(app, event)
            % Arrows 'scale' editbox callback
            app.arrow_scale = eval(get(h,'String'));     % update app.scale
            if app.arrow_scale == 0 | isempty(app.arrow_scale) % no scaling
                app.arrow_scale = [];
            end
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: edit_current
        function edit_current_Callback(app, event)
            tmp = eval(get(app.edit_current,'String'));
            if tmp > 0 & tmp <= app.N % valid number of map
                app.current = tmp;
                guidata(app,app.handles);
                popupmenu_quantity_Callback(app, [], app.handles);
            else
                beep
                set(app.edit_current,'String',app.current);
            end
        end

        % Value changed function: edit_max_clim
        function edit_max_clim_Callback(app, event)
            % second editbox for 'manual' checkbox callback
            app.cmax=str2num(get(h,'String')); % get new value for cmax
            app.alltodisp = 0;
            app.allfields = 1;
            guidata(app,app.handles);
        end

        % Value changed function: edit_min_clim
        function edit_min_clim_Callback(app, event)
            % first editbox for 'manual' checkbox callback
            app.cmin = str2num(get(h,'String')); % update cmin
            app.alltodisp = 0;
            app.allfields = 1;
            guidata(app,app.handles);
        end

        % Value changed function: edit_numcolors
        function edit_numcolors_Callback(app, event)
            % change number of colors
            app.numcolors = str2num(get(h,'String'));
            if isempty(app.numcolors),
                set(h,'String',10);
                app.numcolors = 10;
            end
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % Find the highest parent - figure, and close it.
            while ~strcmpi(get(hObject,'Type'),'figure'),
                hObject = get(hObject,'Parent');
            end
            delete(hObject);
        end

        % Menu selected function: export2MAT
        function export2MAT_Callback(app, event)
            % hObject    handle to export2MAT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            file = [];
                file = inputdlg('File Name','Input Name for CSV File');
                if ~isempty (file)
                    eval(['save ',file{1},' -struct app.handles']);
                else
                    errordlg ('Choose a valid file name !!! ');
                end
        end

        % Menu selected function: export2figure
        function export2figure_Callback(app, event)
            app.export_figure = figure;
            copyobj(app.axes_main,app.export_figure);
            
            set(app.export_figure,'Units','normalized');
            set(get(app.export_figure,'children'),'Units','normalized');
            set(get(app.export_figure,'children'),'Position',[0.13 0.11 0.775 0.815]);
            set(get(app.export_figure,'children'),'Box','on');
            
            if isfield(app.handles,'color_flag') & app.colorbar_flag
                colorbar;
                % mcolorbar; % (get(app.export_figure,'Children'));
            end
            guidata(app, app.handles);
        end

        % Menu selected function: interpolate
        function interpolate_Callback(app, event)
            % hObject    handle to interpolate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            
            [rows,cols] = size(app.u(:,:,1));


            for i = 1:app.N
            
                u = app.u(:,:,i);
                u(u==0) = NaN;
                app.u(:,:,i) = inpaint_nans(u,3);
                u = app.v(:,:,i);
                u(u==0) = NaN;
                app.v(:,:,i) = inpaint_nans(u,3);
            
                %     vector = app.u(:,:,i) + sqrt(-1)*app.v(:,:,i);
                %
                %     [indx,indy] = find(abs(vector) == 0);
                %
                %     while ~isempty(indx)
                %         for z=1:length(indx)
                %             k = [max(3,indx(z))-2:min(rows-2,indx(z))+2];
                %             m = [max(3,indy(z))-2:min(cols-2,indy(z))+2];
                %             tmpvec = vector(k,m);
                %             tmpvec = tmpvec(find(tmpvec));
                %             vector(indx(z),indy(z)) = mean(real(tmpvec))+ sqrt(-1)*mean(imag(tmpvec));
                %         end
                %         try
                %             [indx,indy] = find(abs(vector) == 0);
                %         catch
                %             indx = [];
                %         end
                %     end
            end
            
            
            % Update data
            
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end
            
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            update_gui(app,[],app.handles);
            guidata(app,app.handles);
        end

        % Menu selected function: loadMat
        function loadMat_Callback(app, event)
            % hObject    handle to loadMat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            
            % if isfield(app.handles,'restoreorig')
            %    app.handles = orihandles;
            % end
            app.restoreorig = 1;
            app.dt = 1;
            app.state3d = 0;
            
            
            try
                curdir = cd;
                [coordMatfile,coordMatpath] = uigetfile('*.mat','Coordinates file');
                load(fullfile(coordMatpath,coordMatfile));
            
            
                %     loadVec('F:\Markus\PIV_new\coordinates_61_end.mat')
                if exist('x','var')
                    app.x = x;
                    app.y = max(y(:)) - y;
                    clear x y
                elseif exist('X','var')
                    app.x = X;
                    app.y = max(Y(:)) - Y;
                    clear X Y
                else
                    errordlg('Coordinates file does not include x or X variables');
                    set(app,'pointer','arrow');
                end
                [coordMatfile,coordMatpath] = uigetfile(fullfile(coordMatpath,'*.mat'),'Velocity file');
                load(fullfile(coordMatpath,coordMatfile));
                % loadVec('F:\Markus\PIV_new\vel_field_61_end.mat')
                if exist('fu','var')
                    app.u = fu;
                    app.v = fv;
                    clear fu fv
                elseif exist('U','var')
                    app.u = U;
                    app.v = V;
                    clear U V
                else
                    errordlg('Wrong velocity names');
                end
            
            
                [rows,cols,app.N] = size(app.u);
            
                app.xUnits = 'pix';
                app.velUnits = 'pix/s';
            
                %     [gui_files,gui_path,app.dt,app.scale,app.state3d] = cil_uigetfiles;
                %
                %
                %
                %     app.N = length(gui_files); % number of files selected
                %     if  app.N > 0
                %         app.files = gui_files;
                %         app.path = gui_path;
                %         set(app,'pointer','watch');
                %     else
                %         return
                %     end
                %
                %     switch app.state3d
                %         case 1
                %             if ~isempty(findstr(app.files{1},'v3d'))
                %                 [app.xUnits,app.velUnits,d] = svecread(fullfile(app.path,app.files{1}));
                %                 [rows,cols,k] = size(d);
                %                 [app.u,app.v,app.w] = deal(zeros(rows,cols,app.N+1)); % 11.04.04, Alex
                %                 % Bug fixes June 26, 2004, for the first release.
                %                 app.x = d(:,:,1);
                %                 app.y = d(:,:,2);
                %                 app.z = d(:,:,3);
                %                 app.u(:,:,1) = d(:,:,4);
                %                 app.v(:,:,1) = d(:,:,5);
                %                 app.w(:,:,1) = d(:,:,6);
                % %                 app.x = d(:,:,1)*app.scale/1000;
                % %                 app.y = d(:,:,2)*app.scale/1000;
                % %                 app.z = d(:,:,3)*app.scale/1000;
                % %                 app.u(:,:,1) = d(:,:,4)*app.scale/1000/app.dt;
                % %                 app.v(:,:,1) = d(:,:,5)*app.scale/1000/app.dt;
                % %                 app.w(:,:,1) = d(:,:,6)*app.scale/1000/app.dt;
                %
                %                 for i = 2:app.N
                %                     d = svecread([app.path,filesep,app.files{i}],1,8);
                % %                     app.u(:,:,i) = d(:,:,4)*app.scale/1000/app.dt;
                % %                     app.v(:,:,i) = d(:,:,5)*app.scale/1000/app.dt;
                % %                     app.w(:,:,i) = d(:,:,6)*app.scale/1000/app.dt;
                %                     app.u(:,:,i) = d(:,:,4);
                %                     app.v(:,:,i) = d(:,:,5);
                %                     app.w(:,:,i) = d(:,:,6);
                %                 end
                %                 clear d
                %             end
                %         case 0
                %             if ~isempty(findstr(lower(app.files{1}),'vec'))            % process .vec files
                %                 % read the first file, determine the size
                %                 [app.xUnits,app.velUnits,d] = vecread(fullfile(app.path,app.files{1}));
                %                 [rows,cols,k] = size(d);
                %                 [app.u,app.v] = deal(zeros(rows,cols,app.N+1)); % 11.04.04, Alex
                %                 app.x           = d(:,:,1);
                %                 app.y           = d(:,:,2);
                %                 app.u(:,:,1)    = d(:,:,3);
                %                 app.v(:,:,1)    = d(:,:,4);
                %                 % Bug fixes, June 26, 2004
                % %                 app.x           = d(:,:,1)*app.scale/1000;
                % %                 app.y           = d(:,:,2)*app.scale/1000;
                % %                 app.u(:,:,1)    = d(:,:,3)*app.scale/1000/app.dt;
                % %                 app.v(:,:,1)    = d(:,:,4)*app.scale/1000/app.dt;
                % %
                %                 for i = 2:app.N
                %                     d = vecread([app.path,filesep,app.files{i}],1,5);
                % %                     app.u(:,:,i) = d(:,:,3)*app.scale/1000/app.dt;
                % %                     app.v(:,:,i) = d(:,:,4)*app.scale/1000/app.dt;
                %                     app.u(:,:,i) = d(:,:,3);
                %                     app.v(:,:,i) = d(:,:,4);
                %                 end
                %                 clear d
                %             end
                %     end
            
            catch
                errordlg('Something wrong with MAT files'); % vector -> MAT
                set(app,'pointer','arrow');
                return
            end


            app.current = 1;                      % current file beeing displayed
            % Display first file number, total number of files
            set(app.edit_current,'String',app.current);
            set(app.edit_numfields,'String',app.N);
            
            % Initialize color, number of colors for contours
            app.numcolors = 10;                   % default number of colors
            set(app.edit_numcolors,'String', app.numcolors);
            
            % Cat the mean values at the end
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end
            
            % app.dx = abs(app.x(1,1) - app.x(1,2));
            % app.dy = abs(app.y(1,1) - app.y(2,1));
            % Bug, fixed at 16.06 after the email of Hai
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            % Preallocate memory and calculate all the necessary quantities
            % Fluctuations (last one is zero, we do not need it)
            app.uf = zeros(rows,cols,app.N);
            app.vf = zeros(rows,cols,app.N);
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
            end
            %
            for i = 1:app.N
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            
            % Derivatives
            app.dudx = zeros(rows,cols,app.N+1);
            app.dudy = zeros(rows,cols,app.N+1);
            app.dvdx = zeros(rows,cols,app.N+1);
            app.dvdy = zeros(rows,cols,app.N+1);
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            % Possible future development, eliminating strong gradients
            % on the borders
            % app.dudx(1:2,:,:)       = NaN;
            % app.dudx(end-1:end,:,:) = NaN;
            % app.dudx(:,1:2,:)       = NaN;
            % app.dudx(:,end-1:end,:) = NaN;
            
            %
            % More defaults
            app.arrow_scale = 1;                % default scale
            set(app.edit_arrow_size,'String',app.arrow_scale);
            
            % Default situation, instantaneous, not Ensemble, not fluctuations
            set(app.checkbox_ensemble,'Value',0);
            set(app.checkbox_fluct,'Value',0);
            
            % No colors, no labels
            app.color = 0;                % display colored / black figure
            app.alltodisp = 0;            % default all_to display is unset
            app.allfields = 0;
            app.labelit = 0;              % label for contour
            app.colorbar_flag = 0;
            app.current_index = app.current;
            app.distribOn=0;
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            
            % These are future values, for the TimeBox
            app.i=[];
            app.j=[];
            app.PointsH=[];
            app.Allselected=0;
            
            % app.dx = app.x(1,2) - app.x(1,1);
            % app.dy = app.y(2,1) - app.y(1,1);
            
            % abs() is added on Dec.13,2004. by Alex on IHW_Liberzon computer
            %
            app.gridX = abs(app.x(1,2) - app.x(1,1));
            app.gridY = abs(app.y(1,1) - app.y(2,1));
            
            % Show some and hide some controls
            set(app.popupmenu_quantity,'Visible','on');
            set(app.popupmenu_quantity,'Value',1);
            set(app.popupmenu_contour_type,'Visible','on');
            set(app.popupmenu_contour_type,'Value',1);
            set(app.popupmenu_eachfield,'Visible','on');
            set(app.popupmenu_eachfield,'Value',1);
            
            % Some only part of them in Enable mode
            set(app.checkbox_arrow,'Enable','On');
            set(app.edit_arrow_size,'Enable','On');
            set(app.checkbox_fluct,'Enable','On');
            set(app.checkbox_ensemble,'Enable','On');
            set(app.popupmenu_quantity,'Enable','On');
            set(app.pushbutton_previous,'Enable','On');
            set(app.pushbutton_next,'Enable','On');
            set(app.edit_current,'Enable','On');
            set(app.pushbutton_animate,'Enable','On');
            set(app.pushbutton_save_movie,'Enable','on');
            % set(app.pushbutton_stats,'Enable','on');
            
            
            % Properties
            set(app.popupmenu_quantity,'String',app.inst_list);
            app.property = [];
            
            % ---------------- store all app.handles of spatial controls ------------------
            % app.spatial_controls=[app.checkbox_ensemble,app.checkbox_fluct,app.checkbox_arrow,app.checkbox_arrow_color,...
            %         app.checkbox_label,app.checkbox_colorbar,app.edit_arrow_size,app.edit_numcolors,...
            %         app.edit_current,app.edit_numfields,...
            %         app.text_contour_quantity, app.text_contourtype, app.text_numberofcolors, app.text7,app.text2,...
            %         app.text_arrow_size, app.pushbutton_previous, app.pushbutton_next, ...
            %         app.pushbutton_animate, app.pushbutton_save_movie, ...
            %         app.frame_controls,app.frame8,app.frame_contour_quantity,app.frame_contour_type,app.frame7,...
            %         app.frame_arrow, app.text5, app.popupmenu_quantity,app.popupmenu_contour_type,...
            %         app.popupmenu_eachfield];% ,app.pushbutton_stats];
            
            % -------------- store all app.handles of select controls ----------------
            % app.select_controls=[app.pushbutton_selectpoints,app.pushbutton_selectreg,app.pushbutton_selectall,...
            %          app.pushbutton_profile1,app.pushbutton_time,...
            %          app.pushbutton_reset, app.rowpushbutton, app.colpushbutton];
            
            app.arrow_ctrls=[app.edit_arrow_size,app.checkbox_arrow,app.checkbox_arrow_color];
            % app.stat_controls=[app.ed_mean,app.ed_std,app.ed_min,app.ed_max,app.ed_text,...
            %         app.ed_pushsavestl,app.ed_frame,app.ed_pushclose,app.ed_textmean,...
            %         app.ed_textstd,app.ed_textmax,app.ed_textmin];
            
            
            set(app.pushbutton_spatial,'Enable','on');
            set(app.pushbutton_select,'Enable','on');
            
            set(app,'pointer','arrow');
            
            % added on 10.04.06 for R12SP3 version
            app.axpos = get(app.axes_main,'Position');
            
            % Update all app.handles structure
            guidata(app,app.handles);
            
            % Make default plot
            update_gui(app,[],app.handles);
            return
        end

        % Menu selected function: loadTXT
        function loadTXT_Callback(app, event)
            % hObject    handle to loadTXT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            
            % global origapp.handles;
            % if isfield(app.handles,'restoreorig')
            %    app.handles = origapp.handles;
            % end
            
            app.restoreorig = 1;
            app.state3d = 0;
            app.dt  = 1;
            
            
            try
            
                [gui_files,gui_path] = uigetfile('*.txt','Select the location and the type of the TXT file');
            
                if ~isempty(findstr(gui_files,'_noflt'))
                    data = '_noflt.txt';
                elseif ~isempty(findstr(gui_files,'_flt'))
                    data = '_flt.txt';
                else
                    data = 'txt';
                end
            
                gui_files = ReadTXTDir(gui_path,data);
            
            
                app.N = length(gui_files); % number of files selected
                if  app.N > 0
                    app.files = gui_files;
                    app.path = gui_path;
                    set(app,'pointer','watch');
                else
                    return
                end
            
                %%%%%%%%%%%%%%%%
                d = load(fullfile(gui_path,gui_files{1}));
                d = repmat(d,[1 1 app.N]);
                for i = 2:app.N
                    d(:,:,i) = load(fullfile(gui_path,gui_files{i}));
                end
            
                x = d(:,1,:);
                x = x(x~=0);
                unX = unique(x);
            
                minX = min(unX);
                maxX = max(unX);
                dX = ceil((maxX-minX)/(length(unX)-1));
            
                y = d(:,2,:);
                y = y(y~=0);
                unY = unique(y);
            
                minY = min(unY);
                maxY = max(unY);
                dY = ceil((maxY-minY)/(length(unY)-1));
            
                [app.x,app.y] = meshgrid(minX:dX:maxX,minY:dY:maxY);
                [rows,cols] = size(app.x);
            
                [app.u,app.v] = deal(zeros(rows,cols,app.N+1)); % 11.04.04, Alex
            
                for i = 1:app.N
                    x = d(:,1,i);
                    tmp = d(x~=0,:,i);
                    y = tmp(:,2);
                    x = tmp(:,1);
                    for j = 1:length(x)
                        [m,n] = find(app.x == x(j) & app.y == y(j));
                        app.u(m,n,i) = tmp(j,3);
                        app.v(m,n,i) = tmp(j,4);
                    end
                end
            
                app.xUnits = 'pix';
                app.velUnits = 'pix/dt';
            
                clear d tmp x y
            
            catch
                errordlg('Something wrong with TXT files');
                set(app,'pointer','arrow');
                return
            end
            
            app.current = 1;                      % current file beeing displayed
            % Display first file number, total number of files
            set(app.edit_current,'String',app.current);
            set(app.edit_numfields,'String',app.N);
            
            % Initialize color, number of colors for contours
            app.numcolors = 10;                   % default number of colors
            set(app.edit_numcolors,'String', app.numcolors);
            
            % Cat the mean values at the end
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end
            
            % app.dx = abs(app.x(1,1) - app.x(1,2));
            % app.dy = abs(app.y(1,1) - app.y(2,1));
            % Bug, fixed at 16.06 after the email of Hai
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            % Preallocate memory and calculate all the necessary quantities
            % Fluctuations (last one is zero, we do not need it)
            app.uf = zeros(rows,cols,app.N);
            app.vf = zeros(rows,cols,app.N);
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
            end
            %
            for i = 1:app.N
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            
            % Derivatives
            app.dudx = zeros(rows,cols,app.N+1);
            app.dudy = zeros(rows,cols,app.N+1);
            app.dvdx = zeros(rows,cols,app.N+1);
            app.dvdy = zeros(rows,cols,app.N+1);
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            % Possible future development, eliminating strong gradients
            % on the borders
            % app.dudx(1:2,:,:)       = NaN;
            % app.dudx(end-1:end,:,:) = NaN;
            % app.dudx(:,1:2,:)       = NaN;
            % app.dudx(:,end-1:end,:) = NaN;
            
            %
            % More defaults
            app.arrow_scale = 1;                % default scale
            set(app.edit_arrow_size,'String',app.arrow_scale);
            
            % Default situation, instantaneous, not Ensemble, not fluctuations
            set(app.checkbox_ensemble,'Value',0);
            set(app.checkbox_fluct,'Value',0);
            
            % No colors, no labels
            app.color = 0;                % display colored / black figure
            app.alltodisp = 0;            % default all_to display is unset
            app.allfields = 0;
            app.labelit = 0;              % label for contour
            app.colorbar_flag = 0;
            app.current_index = app.current;
            app.distribOn=0;
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            
            % These are future values, for the TimeBox
            app.i=[];
            app.j=[];
            app.PointsH=[];
            app.Allselected=0;
            
            % app.dx = app.x(1,2) - app.x(1,1);
            % app.dy = app.y(2,1) - app.y(1,1);
            
            % abs() is added on Dec.13,2004. by Alex on IHW_Liberzon computer
            %
            app.gridX = abs(app.x(1,2) - app.x(1,1));
            app.gridY = abs(app.y(1,1) - app.y(2,1));
            
            % Show some and hide some controls
            set(app.popupmenu_quantity,'Visible','on');
            set(app.popupmenu_quantity,'Value',1);
            set(app.popupmenu_contour_type,'Visible','on');
            set(app.popupmenu_contour_type,'Value',1);
            set(app.popupmenu_eachfield,'Visible','on');
            set(app.popupmenu_eachfield,'Value',1);
            
            % Some only part of them in Enable mode
            set(app.checkbox_arrow,'Enable','On');
            set(app.edit_arrow_size,'Enable','On');
            set(app.checkbox_fluct,'Enable','On');
            set(app.checkbox_ensemble,'Enable','On');
            set(app.popupmenu_quantity,'Enable','On');
            set(app.pushbutton_previous,'Enable','On');
            set(app.pushbutton_next,'Enable','On');
            set(app.edit_current,'Enable','On');
            set(app.pushbutton_animate,'Enable','On');
            set(app.pushbutton_save_movie,'Enable','on');
            % set(app.pushbutton_stats,'Enable','on');
            
            
            % Properties
            set(app.popupmenu_quantity,'String',app.inst_list);
            app.property = [];
            
            app.arrow_ctrls=[app.edit_arrow_size,app.checkbox_arrow,app.checkbox_arrow_color];
            
            set(app.pushbutton_spatial,'Enable','on');
            set(app.pushbutton_select,'Enable','on');
            
            set(app,'pointer','arrow');
            
            % added on 10.04.06 for R12SP3 version
            app.axpos = get(app.axes_main,'Position');
            
            % Update all app.handles structure
            guidata(app,app.handles);
            
            % Make default plot
            update_gui(app,[],app.handles);
            return
        end

        % Menu selected function: loadVec
        function loadVec_Callback(app, event)


            try
            
                [gui_files,gui_path,app.dt,app.scale,app.state3d] = cil_uigetfiles;


                app.N = length(gui_files); % number of files selected
                if  app.N > 0
                    app.files = gui_files;
                    app.path = gui_path;
                    set(app,'pointer','watch');
                else
                    return
                end
            
                switch app.state3d
                    case 1
                        if ~isempty(findstr(app.files{1},'v3d'))
                            [app.xUnits,app.velUnits,d] = svecread(fullfile(app.path,app.files{1}));
                            [rows,cols,k] = size(d);
                            [app.u,app.v,app.w] = deal(zeros(rows,cols,app.N+1)); % 11.04.04, Alex
                            % Bug fixes June 26, 2004, for the first release.
                            app.x = d(:,:,1);
                            app.y = d(:,:,2);
                            app.z = d(:,:,3);
                            app.u(:,:,1) = d(:,:,4);
                            app.v(:,:,1) = d(:,:,5);
                            app.w(:,:,1) = d(:,:,6);
                            %                 app.x = d(:,:,1)*app.scale/1000;
                            %                 app.y = d(:,:,2)*app.scale/1000;
                            %                 app.z = d(:,:,3)*app.scale/1000;
                            %                 app.u(:,:,1) = d(:,:,4)*app.scale/1000/app.dt;
                            %                 app.v(:,:,1) = d(:,:,5)*app.scale/1000/app.dt;
                            %                 app.w(:,:,1) = d(:,:,6)*app.scale/1000/app.dt;
            
                            for i = 2:app.N
                                d = svecread([app.path,filesep,app.files{i}],1,8);
                                %                     app.u(:,:,i) = d(:,:,4)*app.scale/1000/app.dt;
                                %                     app.v(:,:,i) = d(:,:,5)*app.scale/1000/app.dt;
                                %                     app.w(:,:,i) = d(:,:,6)*app.scale/1000/app.dt;
                                app.u(:,:,i) = d(:,:,4);
                                app.v(:,:,i) = d(:,:,5);
                                app.w(:,:,i) = d(:,:,6);
                            end
                            clear d
                        end
                    case 0
                        if ~isempty(findstr(lower(app.files{1}),'vec'))            % process .vec files
                            % read the first file, determine the size
                            [app.xUnits,app.velUnits,d] = vecread(fullfile(app.path,app.files{1}));
                            [rows,cols,k] = size(d);
                            [app.u,app.v] = deal(zeros(rows,cols,app.N+1)); % 11.04.04, Alex
                            app.x           = d(:,:,1);
                            app.y           = d(:,:,2);
                            app.u(:,:,1)    = d(:,:,3);
                            app.v(:,:,1)    = d(:,:,4);
                            % Bug fixes, June 26, 2004
                            %                 app.x           = d(:,:,1)*app.scale/1000;
                            %                 app.y           = d(:,:,2)*app.scale/1000;
                            %                 app.u(:,:,1)    = d(:,:,3)*app.scale/1000/app.dt;
                            %                 app.v(:,:,1)    = d(:,:,4)*app.scale/1000/app.dt;
                            %
                            for i = 2:app.N
                                d = vecread([app.path,filesep,app.files{i}],1,5);
                                %                     app.u(:,:,i) = d(:,:,3)*app.scale/1000/app.dt;
                                %                     app.v(:,:,i) = d(:,:,4)*app.scale/1000/app.dt;
                                app.u(:,:,i) = d(:,:,3);
                                app.v(:,:,i) = d(:,:,4);
                            end
                            clear d
                        end
                end
            
            catch
                errordlg('Something wrong with vector files');
                return
            end
            
            app.current = 1;                      % current file beeing displayed
            % Display first file number, total number of files
            set(app.edit_current,'String',app.current);
            set(app.edit_numfields,'String',app.N);
            
            % Initialize color, number of colors for contours
            app.numcolors = 10;                   % default number of colors
            set(app.edit_numcolors,'String', app.numcolors);
            
            
            % app.dx = abs(app.x(1,1) - app.x(1,2));
            % app.dy = abs(app.y(1,1) - app.y(2,1));
            % Bug, fixed at 16.06 after the email of Hai
            app.dx = app.x(1,2) - app.x(1,1);
            app.dy = app.y(2,1) - app.y(1,1);
            
            % 19.03.08 - Alex found one case in which the VEC file
            % is transposed (x is from top to bottom, etc.)
            % therefore dx gave 0 and vorticity was NaN and Inf
            % just for this case:
            
            if app.dx == 0 && app.dy == 0
                app.x = permute(app.x,[2 1]);
                app.y = permute(app.y,[2 1]);
                app.u = permute(app.u,[2 1 3]);
                app.v = permute(app.v,[2 1 3]);
                app.dx = app.x(1,2) - app.x(1,1);
                app.dy = app.y(2,1) - app.y(1,1);
                tmprows = cols;
                cols = rows;
                rows = tmprows;
            end


            % Cat the mean values at the end
            app.u(:,:,app.N+1) = mean(app.u(:,:,1:app.N),3);
            app.v(:,:,app.N+1) = mean(app.v(:,:,1:app.N),3);
            if app.state3d
                app.w(:,:,app.N+1) = mean(app.w(:,:,1:app.N),3);
                app.wf = zeros(rows,cols,app.N);
                for i = 1:app.N
                    app.wf(:,:,i) = app.w(:,:,i) - app.w(:,:,app.N+1);
                end
            end


            % Preallocate memory and calculate all the necessary quantities
            % Fluctuations (last one is zero, we do not need it)
            app.uf = zeros(rows,cols,app.N);
            app.vf = zeros(rows,cols,app.N);
            %
            for i = 1:app.N
                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
            end
            %
            for i = 1:app.N
                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
            end
            
            % Derivatives
            app.dudx = zeros(rows,cols,app.N+1);
            app.dudy = zeros(rows,cols,app.N+1);
            app.dvdx = zeros(rows,cols,app.N+1);
            app.dvdy = zeros(rows,cols,app.N+1);
            %
            for i = 1:app.N+1
                [app.dudx(:,:,i),app.dudy(:,:,i)] = lsgradient(app.u(:,:,i),app.dx, app.dy);
                [app.dvdx(:,:,i),app.dvdy(:,:,i)] = lsgradient(app.v(:,:,i),app.dx, app.dy);
            end
            
            % Possible future development, eliminating strong gradients
            % on the borders
            % app.dudx(1:2,:,:)       = NaN;
            % app.dudx(end-1:end,:,:) = NaN;
            % app.dudx(:,1:2,:)       = NaN;
            % app.dudx(:,end-1:end,:) = NaN;
            
            %
            % More defaults
            app.arrow_scale = 1;                % default scale
            set(app.edit_arrow_size,'String',app.arrow_scale);
            
            % Default situation, instantaneous, not Ensemble, not fluctuations
            set(app.checkbox_ensemble,'Value',0);
            set(app.checkbox_fluct,'Value',0);
            
            % No colors, no labels
            app.color = 0;                % display colored / black figure
            app.alltodisp = 0;            % default all_to display is unset
            app.allfields = 0;
            app.labelit = 0;              % label for contour
            app.colorbar_flag = 0;
            app.current_index = app.current;
            app.distribOn=0;
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            
            % These are future values, for the TimeBox
            app.i=[];
            app.j=[];
            app.PointsH=[];
            app.Allselected=0;
            
            % app.dx = app.x(1,2) - app.x(1,1);
            % app.dy = app.y(2,1) - app.y(1,1);
            
            % abs() is added on Dec.13,2004. by Alex on IHW_Liberzon computer
            %
            app.gridX = abs(app.x(1,2) - app.x(1,1));
            app.gridY = abs(app.y(1,1) - app.y(2,1));
            
            % Show some and hide some controls
            set(app.popupmenu_quantity,'Visible','on');
            set(app.popupmenu_quantity,'Value',1);
            set(app.popupmenu_contour_type,'Visible','on');
            set(app.popupmenu_contour_type,'Value',1);
            set(app.popupmenu_eachfield,'Visible','on');
            set(app.popupmenu_eachfield,'Value',1);
            
            % Some only part of them in Enable mode
            set(app.checkbox_arrow,'Enable','On');
            set(app.edit_arrow_size,'Enable','On');
            set(app.checkbox_fluct,'Enable','On');
            set(app.checkbox_ensemble,'Enable','On');
            set(app.popupmenu_quantity,'Enable','On');
            set(app.pushbutton_previous,'Enable','On');
            set(app.pushbutton_next,'Enable','On');
            set(app.edit_current,'Enable','On');
            set(app.pushbutton_animate,'Enable','On');
            set(app.pushbutton_save_movie,'Enable','on');
            % set(app.pushbutton_stats,'Enable','on');
            
            
            % Properties
            set(app.popupmenu_quantity,'String',app.inst_list);
            app.property = [];
            
            % ---------------- store all app.handles of spatial controls ------------------
            % app.spatial_controls=[app.checkbox_ensemble,app.checkbox_fluct,app.checkbox_arrow,app.checkbox_arrow_color,...
            %         app.checkbox_label,app.checkbox_colorbar,app.edit_arrow_size,app.edit_numcolors,...
            %         app.edit_current,app.edit_numfields,...
            %         app.text_contour_quantity, app.text_contourtype, app.text_numberofcolors, app.text7,app.text2,...
            %         app.text_arrow_size, app.pushbutton_previous, app.pushbutton_next, ...
            %         app.pushbutton_animate, app.pushbutton_save_movie, ...
            %         app.frame_controls,app.frame8,app.frame_contour_quantity,app.frame_contour_type,app.frame7,...
            %         app.frame_arrow, app.text5, app.popupmenu_quantity,app.popupmenu_contour_type,...
            %         app.popupmenu_eachfield];% ,app.pushbutton_stats];
            
            % -------------- store all app.handles of select controls ----------------
            % app.select_controls=[app.pushbutton_selectpoints,app.pushbutton_selectreg,app.pushbutton_selectall,...
            %          app.pushbutton_profile1,app.pushbutton_time,...
            %          app.pushbutton_reset, app.rowpushbutton, app.colpushbutton];
            
            app.arrow_ctrls=[app.edit_arrow_size,app.checkbox_arrow,app.checkbox_arrow_color];
            % app.stat_controls=[app.ed_mean,app.ed_std,app.ed_min,app.ed_max,app.ed_text,...
            %         app.ed_pushsavestl,app.ed_frame,app.ed_pushclose,app.ed_textmean,...
            %         app.ed_textstd,app.ed_textmax,app.ed_textmin];
            
            
            set(app.pushbutton_spatial,'Enable','on');
            set(app.pushbutton_select,'Enable','on');
            
            set(app,'pointer','arrow');
            
            % added on 10.04.06 for R12SP3 version
            app.axpos = get(app.axes_main,'Position');
            
            % Update all app.handles structure
            guidata(app,app.handles);
            
            % Make default plot
            update_gui(app,[],app.handles);
        end

        % Value changed function: popupmenu_eachfield
        function popupmenu_eachfield_Callback(app, event)
            % Each Field, All to display, all fields & manual processing
            
            % get value and assign selected property to app.property which is default to display
            val = get(app.popupmenu_eachfield,'Value');
            switch val
                case 1 % Each Field
                    set(app.edit_min_clim,'Visible','Off');
                    set(app.edit_max_clim,'Visible','Off');
                    set(app.pushbutton_set_clim,'Visible','Off');
                    app.alltodisp = 0;                             % unset all other flags
                    app.allfields = 0;
                case 2 % All to Display
                    set(app.edit_min_clim,'Visible','Off');
                    set(app.edit_max_clim,'Visible','Off');
                    set(app.pushbutton_set_clim,'Visible','Off');
                    app.alltodisp = 1;                % set alltodisp flag which is processed in popupmenu2_callback
                    app.climit = app.climit_prev; % backup current climit which will be changed in popupmenu2_callback
                case 3 % All fields
                    set(app.edit_min_clim,'Visible','Off');
                    set(app.edit_max_clim,'Visible','Off');
                    set(app.pushbutton_set_clim,'Visible','Off');
                    app.alltodisp = 0;
                    app.allfields = 1;            % all_fields is processed in popupmenu2_callback
            
                    if get(app.checkbox_fluct,'Value') == 1  % fluctuations
                        switch get(app.popupmenu_quantity,'Value')
                            case 1
                                app.cmin = 0; app.cmax = 1;
                            case 2
                                app.cmin = min(app.uf(:)); app.cmax = max(app.uf(:));
                            case 3
                                app.cmin = min(app.vf(:)); app.cmax = max(app.vf(:));
                            case 4
                                app.cmin = min(app.uf(:).^2+app.vf(:).^2);
                                app.cmax = max(app.uf(:).^2+app.vf(:).^2);
                            case 5
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = (app.dvdx(:,:,i) - app.dvdx(:,:,app.N+1))-...
                                        (app.dudy(:,:,i) - app.dudy(:,:,app.N+1));
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
                            case 6
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = app.dudx(:,:,i) - app.dudx(:,:,app.N+1);
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
                            case 7
                                %                 app.property = dudy;
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = app.dudy(:,:,i) - app.dudy(:,:,app.N+1);
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
            
                            case 8
                                %                 app.property = dvdx;
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = app.dvdx(:,:,i) - app.dvdx(:,:,app.N+1);
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
                                %
                            case 9
                                %                 app.property = dvdy;
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = app.dvdy(:,:,i) - app.dvdy(:,:,app.N+1);
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
            
                            case 10
                                %                 app.property = dudx+dvdy;
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = (app.dudx(:,:,i) - app.dudx(:,:,app.N+1))+...
                                        (app.dvdy(:,:,i) - app.dvdy(:,:,app.N+1));
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
                            case 11 % s_xy = 0.5*(dvdx+dudy);
                                app.cmin = Inf;
                                app.cmax = -Inf;
                                for i = 1:app.N
                                    tmp = 0.5*(app.dudx(:,:,i) - app.dudx(:,:,app.N+1))-...
                                        (app.dvdy(:,:,i) - app.dvdy(:,:,app.N+1));
                                    app.cmin = min(app.cmin,min(tmp(:)));
                                    app.cmax = max(app.cmax,max(tmp(:)));
                                end
                        end
                    else                                    % no if, instantaneous
                        switch get(app.popupmenu_quantity,'Value')
                            case 1
                                app.cmin = 0; app.cmax = 1;
                            case 2
                                app.cmin = min(app.u(:)); app.cmax = max(app.u(:));
                            case 3
                                app.cmin = min(app.v(:)); app.cmax = max(app.v(:));
                            case 4
                                app.cmin = min(app.u(:).^2+app.v(:).^2);
                                app.cmax = max(app.u(:).^2+app.v(:).^2);
                            case 5
                                app.cmin = min(app.dvdx(:)- app.dudy(:));
                                app.cmax = max(app.dvdx(:)- app.dudy(:));
                            case 6
                                app.cmin = min(app.dudx(:));
                                app.cmax = max(app.dudx(:));
                            case 7
                                app.cmin = min(app.dudy(:));
                                app.cmax = max(app.dudy(:));
                            case 8
                                app.cmin = min(app.dvdx(:));
                                app.cmax = max(app.dvdx(:));
                            case 9
                                app.cmin = min(app.dvdy(:));
                                app.cmax = max(app.dvdy(:));
                            case 10
                                app.cmin = min(app.dudx(:)+app.dvdy(:));
                                app.cmax = max(app.dudx(:)+app.dvdy(:));
                            case 11 % s_xy = 0.5*(dvdx+dudy);
                                app.cmin = 0.5*min(app.dvdx(:)+app.dudy(:));
                                app.cmax = 0.5*max(app.dvdx(:)+app.dudy(:));
                        end
                    end
            
                case 4 % Manual
                    set(app.edit_min_clim,'Visible','On'); % show 2 editboxes to enter the values
                    set(app.edit_max_clim,'Visible','On');
                    set(app.pushbutton_set_clim,'Visible','On');
                    app.alltodisp = 0;
                    % we can use use allfields flag to process 'Manual'  but with cmin & cmax that are entered manually
                    app.allfields = 1;
                    current_clim=get(app.axes_main,'clim');
                    set(app.edit_min_clim,'String',sprintf('%3.2f',current_clim(1)));
                    set(app.edit_max_clim,'String',sprintf('%3.2f',current_clim(2)));
            end
            
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Value changed function: popupmenu_quantity
        function popupmenu_quantity_Callback(app, event)
            % Property Selection callback
            % Assign app.property to present as color or contour later, according
            % to the flags of ensemble, fluctuatinons and possible quantities
            
            
            % --------------Ensemble + Fluct --------------
            if get(app.checkbox_ensemble,'Value') == 1
                if get(app.checkbox_fluct,'Value') == 1
            
                    switch get(app.popupmenu_quantity,'Value')
                        case 1
                            app.property = [];
            
            
                        case 2 % uf^2
                            if isfield(app.handles,'uf2')
                                app.property = app.uf2;
            
                            else
                                app.uf2 = mean(app.uf.^2,3); %u'^2
                                app.property = app.uf2;
                            end
                            %                                app.units='[m/s]^2';
                            app.units = [app.velUnits,'^2']; % '[m/s]^2';
                        case 3 % vf^2
                            if isfield(app.handles,'vf2')
                                app.property = app.vf2;
                            else
                                app.vf2 = mean(app.vf.^2,3); %u'^2
                                app.property = app.vf2;
                            end
                            app.units = [app.velUnits,'^2']; %'[m/s]^2';
                        case 4
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units=['[',app.velUnits,'^2/s^2]'];
                            else
                                app.units = ['[',app.velUnits,'^2/\Delta t^2]'];
                            end
            
            
                            if isfield(app.handles,'rs')
                                app.property = app.rs;
                            else
                                app.rs = -1 * mean(app.uf.*app.vf,3); % Reynolds stress
                                app.property = app.rs;
                            end
                            % --------------------------
                        case 5
                            app.units='[]';
                            if isfield(app.handles,'Tu'),
                                app.property = app.Tu;
                            else
                                meanU = abs(mean(mean(app.u(:,:,app.N+1))));
                                meanV = abs(mean(mean(app.v(:,:,app.N+1))));
                                if  meanU > meanV   % check which one is streamwise
                                    app.Tu = sqrt(mean(app.uf.^2,3))/meanU;
                                else
                                    app.Tu = sqrt(mean(app.uf.^2,3))/meanV;
                                end
                                app.property = app.Tu;
                            end
                            % ----------------------------
                        case 6
                            app.units='[]';
                            if isfield(app.handles,'Tv'),
                                app.property = app.Tv;
                            else
                                meanU = abs(mean(mean(app.u(:,:,app.N+1))));
                                meanV = abs(mean(mean(app.v(:,:,app.N+1))));
                                if  meanU > meanV   % check which one is streamwise
                                    app.Tv = sqrt(mean(app.vf.^2,3))/meanU;
                                else
                                    app.Tv = sqrt(mean(app.vf.^2,3))/meanV;
                                end
                                app.property = app.Tv;
                            end
                        case 7 % dissipation, Alex, 01.04.04
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s^2]'  ;
                            else
                                app.units = '[1/\Delta t^2]';
                            end
            
                            if isfield(app.handles,'diss')
                                app.property = app.diss;
                            else
                                app.diss = zeros(size(app.x));
                                for i = 1:app.N
                                    dudx = app.dudx(:,:,i) - app.dudx(:,:,app.N+1);
                                    dudy = app.dudy(:,:,i) - app.dudy(:,:,app.N+1);
                                    dvdx = app.dvdx(:,:,i) - app.dvdx(:,:,app.N+1);
                                    dvdy = app.dvdy(:,:,i) - app.dvdy(:,:,app.N+1);
                                    app.diss = app.diss + (dudx).^2 + (dvdy).^2 + 2*(dudy + dvdx).^2;
                                end
                                app.diss = app.diss/app.N;
                                app.property = app.diss;
                            end
                        case 8 % energy production - <u'v'>S_xy
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[m^2/s^3]' ;
                            else
                                app.units = '[pixel^2/\Delta t^3]';
                            end
            
                            if isfield(app.handles,'prod')
                                app.property = app.prod;
                            else
                                app.prod = -1*( mean(app.uf.*app.vf,3).*( app.dudy(:,:,app.N+1) + app.dvdx(:,:,app.N+1) ) + ...
                                    mean(app.uf.*app.uf,3).*app.dudx(:,:,app.N+1)  +...
                                    mean(app.vf.*app.vf,3).*app.dvdy(:,:,app.N+1));
                                app.property = app.prod;
                            end
                    end % of switch
                    % ------------------------------------------- Ensemble ------
                else % of fluct, means only ensemble is chosen
                    switch get(app.popupmenu_quantity,'Value')
                        case 1
                            app.property = [];    % default variable, choose properly later, alex, 16.02
            
                        case 2
                            app.units = app.velUnits; ;
            
                            app.property = app.u(:,:,app.current);
                        case 3
                            app.units = app.velUnits; ;
                            app.property = app.v(:,:,app.current);
                        case 4
                            app.units = app.velUnits; ;
                            app.property = sqrt(app.u(:,:,app.current).^2+app.v(:,:,app.current).^2);%Velocity Magnitude
                        case 5 % vorticity;
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
            
                            app.property = app.dvdx(:,:,app.current) - app.dudy(:,:,app.current);
                        case 6
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudx(:,:,app.current);
                        case 7
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudy(:,:,app.current);
                        case 8
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dvdx(:,:,app.current);
                        case 9
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dvdy(:,:,app.current);
                        case 10
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudx(:,:,app.current) + app.dvdy(:,:,app.current);
                        case 11 % s_xy
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = 0.5*(app.dvdx(:,:,app.current) + app.dudy(:,:,app.current));
                    end
                end % if fluct, else
            else % if no ensemble, it might be u or uf
                if get(app.checkbox_fluct,'Value') == 1  % uf case
            
                    % Derivative of the velocity fluctuations field
                    dudx = app.dudx(:,:,app.current) - app.dudx(:,:,app.N+1);
                    dudy = app.dudy(:,:,app.current) - app.dudy(:,:,app.N+1);
                    dvdx = app.dvdx(:,:,app.current) - app.dvdx(:,:,app.N+1);
                    dvdy = app.dvdy(:,:,app.current) - app.dvdy(:,:,app.N+1);
            
            
                    switch get(app.popupmenu_quantity,'Value')
                        case 1
                            app.property = [];    % default variable, choose properly later, alex, 16.02
            
                        case 2
                            app.units = app.velUnits; ;
                            app.property = app.uf(:,:,app.current);
                            app.cmin = min(app.uf(:)); app.cmax = max(app.uf(:));
                        case 3
                            app.units = app.velUnits; ;
                            app.property = app.vf(:,:,app.current);
                            app.cmin = min(app.vf(:)); app.cmax = max(app.vf(:));
                        case 4
                            app.units = app.velUnits; ;
                            app.property = sqrt(app.uf(:,:,app.current).^2+app.vf(:,:,app.current).^2);%Velocity Magnitude
                            app.cmin = min(app.uf(:).^2+app.vf(:).^2);
                            app.cmax = max(app.uf(:).^2+app.vf(:).^2);
                        case 5
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dvdx - dudy;
                        case 6
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dudx;
                        case 7
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dudy;
                        case 8
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dvdx;
                        case 9
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dvdy;
                        case 10
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = dudx+dvdy;
                        case 11 % s_xy
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = 0.5*(dvdx+dudy);
                    end
                else % fluct, means nothing is selected, u values
                    switch get(app.popupmenu_quantity,'Value')
                        case 1
                            app.property = [];    % default variable, choose properly later, alex, 16.02
            
                        case 2
                            app.units = app.velUnits; ;
                            app.property = app.u(:,:,app.current);
                        case 3
                            app.units = app.velUnits; ;
                            app.property = app.v(:,:,app.current);
                        case 4
                            app.units = app.velUnits; ;
                            app.property = sqrt(app.u(:,:,app.current).^2 + app.v(:,:,app.current).^2);%Velocity Magnitude
                        case 5
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dvdx(:,:,app.current) - app.dudy(:,:,app.current); % omega;
                        case 6 % dudx
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudx(:,:,app.current);
                        case 7
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudy(:,:,app.current);
                        case 8
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dvdx(:,:,app.current);
                        case 9
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dvdy(:,:,app.current);
                        case 10
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = app.dudx(:,:,app.current) + app.dvdy(:,:,app.current);
                        case 11 % s_xy
                            if ~isempty(findstr(app.velUnits,'s'))
                                app.units='[1/s]'  ;
                            else
                                app.units = '[1/\Delta t]';
                            end
                            app.property = 0.5*(app.dvdx(:,:,app.current) + app.dudy(:,:,app.current));
                    end
                end
            end
            
            if ~isempty(app.property) % something was selected
                if strcmp( get(app.edit_arrow_size,'Enable'),'on')
                    set(app.checkbox_arrow_color,'Enable','on');
                end
            end
            
            % Record the string which is in the quantity popupmenu in something
            tmp = cellstr(get(app.popupmenu_quantity,'String'));
            if strcmp(app.previous_quantity,tmp{get(app.popupmenu_quantity,'Value')}) == 0 & ...
                    get(app.popupmenu_eachfield,'Value') == 3 % all fields, update it
                app.previous_quantity = tmp{get(app.popupmenu_quantity,'Value')};
                popupmenu_eachfield_Callback(app, [], app.handles);
            else
                app.previous_quantity = tmp{get(app.popupmenu_quantity,'Value')};
                guidata(app,app.handles);
                update_gui(app,[],app.handles);
            end
        end

        % Value changed function: pushbutton_animate
        function pushbutton_animate_Callback(app, event)
            % animate button callback
            
            if get(app.pushbutton_animate,'Value') == 1
                %     app.tmpenableprop = strcmp(get(app.spatial_controls,'Enable'),'on')
                %     setenableprop(app.spatial_controls,'off');
                %     setenableprop(app.pushbutton_animate,'on');
                startpoint = app.current;
                for i = startpoint:app.N
                    if get(app.pushbutton_animate,'Value') == 0
                        break;
                    end
                    app.current = i;
                    set(app.edit_current,'String',app.current);
                    delete(get(app.axes_main,'children'));
                    guidata(app,app.handles);
                    popupmenu_quantity_Callback(app, [], app.handles);
                    drawnow;
                end
            else
            
            end
            set(app.pushbutton_animate,'Value',0);
        end

        % Button pushed function: pushbutton_next
        function pushbutton_next_Callback(app, event)
            if app.current < app.N
                app.current = app.current + 1;      % update app.current
                set(app.edit_current,'String',app.current);
                guidata(app,app.handles);
                delete(get(app.axes_main,'children'));
                popupmenu_quantity_Callback(app, [], app.handles);
            else
                beep;
            end
        end

        % Button pushed function: pushbutton_previous
        function pushbutton_previous_Callback(app, event)
            if app.current > 1
                app.current = app.current - 1;          % update app.current
                set(app.edit_current,'String',app.current);        % display num. of current file being processed
                delete(get(app.axes_main,'children'));
                guidata(app,app.handles);
                popupmenu_quantity_Callback(app, [], app.handles);
            else
                beep;
            end
        end

        % Button pushed function: pushbutton_profile1
        function pushbutton_profile1_Callback(app, event)
            if isempty(app.property) | isempty(app.i)
                errordlg('First, pick the quantity and region of interest !!!');
            else
                distribHandle = distrib(app.handles);  % call to spatialbox
                app.distribOn = 1;
            end;
            
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_reset_Callback(app, event)
            
            app.i = []; app.j = [];
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            set(app.pushbutton_selectpoints,'Enable','on');
            set(app.pushbutton_selectreg,'Enable','on');
            set(app.colpushbutton,'Enable','on');
            set(app.pushbutton_selectall,'Enable','on');
            set(app.rowpushbutton,'Enable','on');
            guidata(app,app.handles);
            update_gui(gcbo,[],guidata(gcbo));
        end

        % Value changed function: pushbutton_save_movie
        function pushbutton_save_movie_Callback(app, event)
            
            if get(app.pushbutton_save_movie,'Value') == 1
                file = [];
                file = inputdlg('File Name','Input File Name for the movie');
                if isempty(file) | exist(file{1},'file') | exist([file{1},'.avi'],'file')
                    set(app.pushbutton_save_movie,'Value',0);
                    return
                end
                app.mov = avifile(file{1},'compression','none','quality',100,'fps',15);
                % It is possible to change the compression and the video codec, and the
                % frame rate
                % mov = avifile(file{1},'compression','Indeo5','quality',100,'fps',5);
            
            
                startpoint = app.current;
                for i = startpoint:app.N
            
                    app.current = i;
                    set(app.edit_current,'String',app.current);
                    delete(get(app.axes_main,'children'));
                    guidata(app,app.handles);
                    popupmenu_quantity_Callback(app, [], app.handles);
                    F = getframe(app.axes_main);
                    if get(app.pushbutton_save_movie,'Value') == 0
                        break;
                    end
                    app.mov = addframe(app.mov,F);
                end
            else
                if isfield(app.handles,'mov')
                    app.mov = close(app.mov);
                    app.handles = rmfield(app.handles,'mov');
                end
                set(app.pushbutton_save_movie,'Value',0);
            end
            set(app.pushbutton_save_movie,'Value',0);
        end

        % Button pushed function: pushbutton_select
        function pushbutton_select_Callback(app, event)
            % hObject    handle to pushbutton_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.handles    structure with app.handles and user data (see GUIDATA)
            set(app.spatial_controls,'Visible','off');
            % set(app.pushbutton_set_clim,'Visible','off');
            set(app.select_controls,'Visible','on');
            % set(findobj(app.spatial_controls,'type','uicontrol'),'Enable','Off');
            set(findobj(app.select_controls,'type','uicontrol'),'Enable','On');
            set(app.pushbutton_select,'String','> Select <');
            set(app.pushbutton_spatial,'String','Spatial');
            
            
            set(app.pushbutton_spatial,'FontWeight','normal');
            set(app.pushbutton_select,'FontWeight','bold');
            val = get(app.popupmenu_eachfield,'Value');
            app.i = []; app.j = [];
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            
            update_gui(gcbo,[],guidata(gcbo));
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_selectall
        function pushbutton_selectall_Callback(app, event)
            set(app.pushbutton_selectpoints,'Enable','off');
            set(app.rowpushbutton,'Enable','off');
            set(app.colpushbutton,'Enable','off');
            set(app.pushbutton_selectreg,'Enable','off');
            set(app.pushbutton_selectall,'Enable','off');
            app.Allselected=1;
            update_gui(hObject,[],guidata(hObject));
            app.i=[]; app.j=[]; app.previousSel=[];
            limX=xlim; limY=ylim;
            x1 = [limX(1,1) limX(1,2) limX(1,2) limX(1,1) limX(1,1)];
            y1 = [limY(1,1) limY(1,1) limY(1,2) limY(1,2) limY(1,1)];
            hold on
            app.selectionbox = plot(x1,y1,':b','LineWidth',4);
            hold off
            leftcolX    =   1;
            rightcolX   =   fix((limX(1,2)-limX(1,1))/app.gridX)+1;
            bottomrowY  =   1;
            uprowY      =   fix((limY(1,2)-limY(1,1))/app.gridY)+1;
            % uprowY      =   fix((limY(1,2)-limY(1,1))/app.gridY+1)+1;
            numofcols=rightcolX-leftcolX+1;
            sizeI=size(app.i,1);
            sizeJ=size(app.j,1);
            for i1 = bottomrowY:uprowY
                app.i(sizeI+1:sizeI+numofcols,1)=i1;
                app.j(sizeJ+1:sizeJ+numofcols,1)=leftcolX:rightcolX;
                sizeI=sizeI+numofcols; sizeJ=sizeJ+numofcols;
            end
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_selectpoints
        function pushbutton_selectpoints_Callback(app, event)
            
            
            set(app.rowpushbutton,'Enable','off');
            set(app.pushbutton_selectreg,'Enable','off');
            set(app.colpushbutton,'Enable','off');
            set(app.pushbutton_selectall,'Enable','off');


            set(app.axes_main,'NextPlot','Add');
            limX = xlim;
            limY = ylim;
            leftcolX   = 1;
            bottomrowY = 1;
            
            
            while 1
            
                [x1,y1, buttonNumber] = ginput(1);
            
                % When the right button is pressed, stop the loop
                if (buttonNumber == 2) | (buttonNumber==3)
                    break;
                end;
                col = fix (( x1 - limX(1,1) )/ app.gridX+0.5  )+1;
                row = fix(( y1 - limY(1,1) )/ app.gridY+0.5  )+1;
            
                % check for errors ----------------
                if col<1 | col>(fix((limX(1,2)-limX(1,1))/app.gridX)+1) | row<1 | row...
                        >(fix((limY(1,2)-limY(1,1))/app.gridY)+1);
                    guidata(app,app.handles);
                    return;
                end;
                % ---------------------------------
                sizeI = size(app.i,1);
                rightcolX = fix(( limX(1,2)-limX(1,1) )/  app.gridX )+1;
                uprowY    = fix(( limY(1,2)-limY(1,1) )/  app.gridY )+1;
                sizeJ = size(app.j,1);
                numofcols = rightcolX - leftcolX + 1;
                numofrows = uprowY - bottomrowY + 1;
            
                app.i(sizeI+1,1) = row;
                app.j(sizeJ+1,1) = col;
            
                line(limX(1,1)+(col-1)*...
                    app.gridX,limY(1,1)+(row-1)*app.gridY,'Marker','o','Color','k','MarkerSize',8);
            
            end;
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_selectreg
        function pushbutton_selectreg_Callback(app, event)
            % check if all selected is off
            set(app.pushbutton_selectpoints,'Enable','off');
            set(app.rowpushbutton,'Enable','off');
            set(app.colpushbutton,'Enable','off');
            set(app.pushbutton_selectall,'Enable','off');
            
            k       =   waitforbuttonpress;
            point1  =   get(gca,'CurrentPoint');    % button down detected
            % point1 is 2x3 matrix, first 2 elements are x,y
            finalRect = rbbox;                   % return figure units
            point2  =    get(gca,'CurrentPoint');    % button up detected
            point1  =    point1(1,1:2);              % extract x and y
            point2  =    point2(1,1:2);
            p1      =    min(point1,point2);             % calculate locations
            offset  =    abs(point1-point2);         % and dimensions
            
            
            limX = xlim; limY = ylim;                     % get Axis Limits
            % -------------- calculate columns & rows ------------------
            leftcolX = fix(( p1(1)-limX(1,1) )/ app.gridX +1) + 1;
            rightcolX = fix(( p1(1)+offset(1)-limX(1,1) )/ app.gridX )+1;
            bottomrowY =  fix(( p1(2)-limY(1,1) )/ app.gridY+1 )+1;
            uprowY = fix(( p1(2) + offset(2) - limY(1,1) )/ app.gridY)+1;
            
            
            % -------boundary check ----------------
            plotstateX=0;plotstateY=0;
            if leftcolX<1     leftcolX=1;
                plotstateY= 1;
            end;
            rightLimit=fix((limX(1,2)-limX(1,1))/app.gridX)+1;
            if rightcolX>rightLimit  rightcolX=rightLimit; end;
            uprowLimit=fix((limY(1,2)-limY(1,1))/app.gridY)+1;
            if bottomrowY<1  bottomrowY=1;
                plotstateX= 1;   % we need it to plot in right way
            end
            if uprowY>uprowLimit     uprowY=uprowLimit; end;
            
            % --------- selection checking ---------------
            sizeI = size(app.i,1);
            sizeJ = size(app.j,1);
            numofcols = rightcolX-leftcolX+1;
            numofrows = uprowY-bottomrowY+1;
            
            
            % -------------- errorchecking --------
            if ~isempty(app.previousSel)
                a = app.previousSel;
                if ((rightcolX-leftcolX) == a(2)-a(1) & a(2) == rightcolX & app.rowlock~=1)
                    app.columnlock=1;
                elseif    ((uprowY-bottomrowY)==a(4)-a(3) & a(4)==uprowY & app.columnlock~=1)
                    app.rowlock=1;
                else
                    errordlg('Your Selection is Invalid...');
                    return;
                end;
            end;
            
            if ismember([bottomrowY leftcolX],[app.i app.j],'rows') | ismember([bottomrowY rightcolX],[app.i app.j],'rows') | ...
                    ismember([uprowY leftcolX],[app.i app.j],'rows') | ismember([uprowY rightcolX],[app.i app.j],'rows')
                errordlg('Your Selection is Invalid...');
                return;
            end


            % --------------------- Fill loop ----------------------------
            
            for i1 = bottomrowY:uprowY
                app.i(sizeI+1:sizeI+numofcols,1)    =   i1;
                app.j(sizeJ+1:sizeJ+numofcols,1)    =   leftcolX:rightcolX;
                sizeI   =   sizeI+numofcols;
                sizeJ   =   sizeJ+numofcols;
            end
            % ------------- plot ----------------------
            
            lx_box=limX(1,1)+(leftcolX-1)*app.gridX*~plotstateY;
            rx_box=limX(1,1)+(rightcolX-1)*app.gridX;
            uy_box=limY(1,1)+(uprowY-1)*app.gridY;
            by_box=limY(1,1)+(bottomrowY-1)*app.gridY*~plotstateX;
            
            
            x1 = [lx_box rx_box rx_box lx_box lx_box];
            y1 = [by_box by_box uy_box uy_box by_box];
            hold on
            app.selectionbox = plot(x1,y1,'--b','LineWidth',1.5);
            hold off
            app.previousSel=[leftcolX rightcolX bottomrowY uprowY];
            
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_set_clim
        function pushbutton_set_clim_Callback(app, event)
            % set button callback
            app.cmin = eval(get(app.edit_min_clim,'String'));
            app.cmax = eval(get(app.edit_max_clim,'String'));
            if app.cmin >= app.cmax
                errordlg('Wrong limits, min should be less than max', 'Error','modal');
                current_clim = get(app.axes_main,'clim');
                app.cmin = current_clim(1);
                app.cmax = current_clim(2);
                set(app.edit_min_clim,'String',sprintf('%3.2f',app.cmin));
                set(app.edit_max_clim,'String',sprintf('%3.2f',app.cmax));
            end
            guidata(app,app.handles);
            update_gui(app,[],app.handles);
        end

        % Button pushed function: pushbutton_spatial
        function pushbutton_spatial_Callback(app, event)
            
            set(app.spatial_controls,'Visible','on');
            set(app.select_controls,'Visible','off');
            set(app.pushbutton_spatial,'FontWeight','bold');
            set(app.pushbutton_select,'FontWeight','normal');
            set(app.pushbutton_spatial,'String','> Spatial <');
            set(app.pushbutton_select,'String','Select');
            val = get(app.popupmenu_eachfield,'Value');
            if val==4   % in case if manual is selected
                set(app.edit_min_clim,'Visible','On'); % show 2 editboxes to enter the values
                set(app.edit_max_clim,'Visible','On');
                set(app.pushbutton_set_clim,'Visible','On');
            end
            
            % save selection
            app.i = []; app.j = [];
            app.rowlock=0; app.columnlock=0;
            app.previousSel=[];
            
            update_gui(gcbo,[],guidata(gcbo));
            guidata(app,app.handles);
        end

        % Button pushed function: pushbutton_time
        function pushbutton_time_Callback(app, event)
            if ~isempty(app.i)
                timeboxHandle = timebox(app.handles); % timebox includes both versions with if ... else
                guidata(app,app.handles);
            else
                errordlg('Select region of interest');
            end
        end

        % Button pushed function: rowpushbutton
        function rowpushbutton_Callback(app, event)
            
            set(app.pushbutton_selectpoints,'Enable','off');
            set(app.pushbutton_selectreg,'Enable','off');
            set(app.colpushbutton,'Enable','off');
            set(app.pushbutton_selectall,'Enable','off');
            
            set(app.axes_main,'NextPlot','Add');
            limX = xlim;
            limY = ylim;
            leftcolX   = 1;
            bottomrowY = 1;
            
            
            while 1
            
                [x1,y1, buttonNumber] = ginput(1);
            
                % When the right button is pressed, stop the loop
                if (buttonNumber == 2) | (buttonNumber==3)
                    break;
                end;
                col = fix (( x1 - limX(1,1) )/ app.gridX+0.5  )+1;
                row = fix(( y1 - limY(1,1) )/ app.gridY+0.5  )+1;
                % check for errors ----------------
                if col<1 || col>(fix((limX(1,2)-limX(1,1))/app.gridX)+1) || row<1 || row...
                        >(fix((limY(1,2)-limY(1,1))/app.gridY)+1)
                    guidata(app,app.handles);
                    return;
                end
                % ---------------------------------
                sizeI = size(app.i,1);
                rightcolX = fix(( limX(1,2)-limX(1,1) )/  app.gridX )+1;
                uprowY    = fix(( limY(1,2)-limY(1,1) )/  app.gridY )+1;
                % uprowY    = fix(( limY(1,2)-limY(1,1) )/  app.gridY +1)+1; % Alex,
                % 26.10
                sizeJ = size(app.j,1);
                numofcols = rightcolX - leftcolX + 1;
                numofrows = uprowY - bottomrowY + 1;
            
                app.i(sizeI+1:sizeI+numofrows,1) = 1:uprowY ;
                app.j(sizeJ+1:sizeJ+numofrows,1) = col;
                row = 1:uprowY;
                topLeft(1)=uprowY; bottomRight(1)=1;
            
                line(limX(1,1)+(col-1)*...
                    app.gridX,limY(1,1)+(row-1)*app.gridY,'Marker','o','Color','k','MarkerSize',8);
            
            
            end
            guidata(app,app.handles);
        end

        % Value changed function: popupmenu_contour_type
        function update_gui(app, event)
            % update_gui is responsible for update of the screen with current property and contour type
            
            axes(app.axes_main);
            delete(get(app.axes_main,'children'));
            
            if get(app.popupmenu_quantity,'Value') == 1 ,
                set(app.checkbox_label,'Enable','Off');
                set(app.checkbox_colorbar,'Enable','Off');
                set(app.text_numberofcolors,'Enable','Off');
                set(app.edit_numcolors,'Enable','Off');
                set(app.popupmenu_eachfield,'Enable','Off');
                set(app.edit_max_clim,'Enable','Off');
                set(app.edit_min_clim,'Enable','Off');
                set(app.pushbutton_set_clim,'Enable','Off');
                set(app.popupmenu_contour_type,'Enable','Off');
            else
                set(app.checkbox_label,'Enable','On');
                set(app.checkbox_colorbar,'Enable','On');
                set(app.text_numberofcolors,'Enable','On');
                set(app.edit_numcolors,'Enable','On');
                set(app.popupmenu_eachfield,'Enable','On');
                set(app.edit_max_clim,'Enable','On');
                set(app.edit_min_clim,'Enable','On');
                set(app.pushbutton_set_clim,'Enable','On');
                set(app.popupmenu_contour_type,'Enable','On');
            end
            
            %-------------- Contour -----------------
            if ~isempty(app.property)
            
                switch (get(app.popupmenu_contour_type,'Value'))
                    case 1
                        cla reset;
                    case 2
                        % Flood contour - type
                        [app.C,app.CH] = contourf(app.x,app.y,app.property,app.numcolors);
                        set(app.CH,'edgecolor','none');
                        % set (app.CH,'FaceAlpha',0.5);    % if you want transparency, uncomment this.... very slow!!!
                    case 3
                        %     Color Line
                        [app.C,app.CH] = contour(app.x,app.y,app.property,app.numcolors);
                    case 4
                        %Flood + Line
                        [app.C,app.CH] = contourf(app.x,app.y,app.property,app.numcolors);
                    case 5
                        %Black Line
                        [app.C,app.CH] = contour(app.x,app.y,app.property,app.numcolors);
                        set(app.CH,'edgecolor','black');
                end
            
                % -----------------------------------------
                app.climit_prev = get(gca,'clim');
                % ----------------------------------------------------------
                if app.alltodisp == 1                  % all to display checkbox is processed here
                    set(gca,'CLim',app.climit);
                elseif app.allfields == 1               % all_fields checkbox is processed here
                    if get(app.popupmenu_eachfield,'Value')==4;  % in case of manual
                        app.cmin = str2num(get(app.edit_min_clim,'String'));
                        app.cmax = str2num(get(app.edit_max_clim,'String'));
                    end
                    set(gca,'CLim',[app.cmin app.cmax]);
                end
                % ----------------------------------------------------------
                if app.labelit == 1  % label if necessary
                    if get(app.popupmenu_contour_type,'Value')>1
                        clabel(app.C,app.CH);
                    end;
                end
                % -----------------------------------------------------------
                if app.colorbar_flag                       % colorbar if necessary
                    app.colorbar = colorbar('peer',app.axes_main,'East');
                end
                % -------------------------------------------------
            else    % if property is empty
                cla reset;   %bugfix 0304 ----- if property is empty dont dispay map...
                app.color = 0;      % disable color
                set(app.checkbox_arrow_color,'Enable','off');
                set(app.checkbox_arrow_color,'Value',0);
            end
            % ----------------------end of ~isempty branch ---------------
            % create arrows
            if get(app.checkbox_arrow,'Value') == 1
                if app.color == 1                 % arrows colored / b&w
                    hold on;
                    if get(app.checkbox_fluct,'Value') == 1 % checkbox_fluct On
                        if ~isfield(app.handles ,'uf')
                            for i = 1:app.N
                                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
                            end
                        end
                        if ~isfield(app.handles ,'vf')
                            for i = 1:app.N
                                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                            end
                        end
            
                        if get(app.checkbox_ensemble,'Value') == 0
                            app.color_quiver = quiverc(app.x,app.y,app.uf(:,:,app.current),app.vf(:,:,app.current),app.arrow_scale,app.property);
                        else
                            app.color_quiver = quiverc(app.x,app.y,app.u(:,:,app.current),app.v(:,:,app.current),app.arrow_scale,app.property);
                            % nothing to display, arrows of ensemble + fluctuation = 0
                            % by definition
                            set(app.color_quiver,'Visible','Off');
                        end
                    else
                        app.color_quiver = quiverc(app.x,app.y,app.u(:,:,app.current),app.v(:,:,app.current),app.arrow_scale,app.property);
                    end
                    hold off;
                    if app.colorbar_flag == 1                      % colorbar if necessary
                        delete(app.colorbar);
                        app.colorbar = colorbar('peer',app.axes_main,'East');
                        %             app.colorbar = mcolorbar('EastOutside','peer',app.axes_main);
                    end
                else
                    hold on;
                    % ----------------------checknox_fluct is checked -------------
                    if get(app.checkbox_fluct,'Value') == 1
                        if ~isfield(app.handles ,'uf')
                            for i = 1:app.N
                                app.uf(:,:,i) = app.u(:,:,i) - app.u(:,:,app.N+1);
                            end
                        end
                        if ~isfield(app.handles ,'vf')
                            for i = 1:app.N
                                app.vf(:,:,i) = app.v(:,:,i) - app.v(:,:,app.N+1);
                            end
                        end
            
                        if get(app.checkbox_ensemble,'Value') == 0
                            app.quiver = quiver(app.x,app.y,app.uf(:,:,app.current),app.vf(:,:,app.current),app.arrow_scale,'k');
                        else
                            app.quiver = quiver(app.x,app.y,app.u(:,:,app.current),app.v(:,:,app.current),app.arrow_scale,'k');
                            % nothing to display, arrows of ensemble + fluctuation = 0
                            % by definition
                            set(app.quiver,'Visible','Off');
                        end
                    else
                        app.quiver = quiver(app.x,app.y,app.u(:,:,app.current),app.v(:,:,app.current),app.arrow_scale,'k');
                    end
                    hold off;
                end
            end
            
            set(app.axes_main,'XLim',[min(app.x(:)),max(app.x(:))]);
            set(app.axes_main,'YLim',[min(app.y(:)),max(app.y(:))]);
            xlabel(['x ',app.xUnits]); % [m]');
            ylabel(['y ',app.xUnits]); % 'y [m]');
            
            if isfield(app.handles,'colorbar') & get(app.checkbox_colorbar,'Value') == 1
                axpos = app.axpos; % 10.04.06
                set(app.axes_main,'Units','normalized','Position',[axpos(1),axpos(2),axpos(3)-.025,axpos(4)]);
                set(app.colorbar,'Units','normalized','Position',[axpos(1)+axpos(3)+.02,axpos(2),.025,axpos(4)]);
            end
            guidata(app,app.handles);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_gradpiv = uifigure;
            app.figure_gradpiv.Name = 'Spatial Toolbox for Matlab 7SP3 with Filter, Crop, Interpolation, 28.07.06';
            app.File = uimenu(app.figure_gradpiv);
            app.File.Text = 'File';
            app.loadVec = uimenu(app.File);
            app.loadVec.MenuSelectedFcn = createCallbackFcn(app, @loadVec_Callback, true);
            app.loadVec.Accelerator = 'V';
            app.loadVec.Text = 'Load Vector Files';
            app.loadMat = uimenu(app.File);
            app.loadMat.MenuSelectedFcn = createCallbackFcn(app, @loadMat_Callback, true);
            app.loadMat.Separator = 'on';
            app.loadMat.Accelerator = 'M';
            app.loadMat.Text = 'Load MAT files';
            app.loadTXT = uimenu(app.File);
            app.loadTXT.MenuSelectedFcn = createCallbackFcn(app, @loadTXT_Callback, true);
            app.loadTXT.Separator = 'on';
            app.loadTXT.Text = 'Load TXT files';
            app.export2figure = uimenu(app.File);
            app.export2figure.MenuSelectedFcn = createCallbackFcn(app, @export2figure_Callback, true);
            app.export2figure.Separator = 'on';
            app.export2figure.Text = 'Export to figure';
            app.export2MAT = uimenu(app.File);
            app.export2MAT.MenuSelectedFcn = createCallbackFcn(app, @export2MAT_Callback, true);
            app.export2MAT.Text = 'Export to MAT file';
            app.exit = uimenu(app.File);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Text = 'Exit';
            app.Data = uimenu(app.figure_gradpiv);
            app.Data.Text = 'Data';
            app.Filter = uimenu(app.Data);
            app.Filter.Text = 'Filter';
            app.Global = uimenu(app.Filter);
            app.Global.MenuSelectedFcn = createCallbackFcn(app, @Global_Callback, true);
            app.Global.Text = 'Global';
            app.Median = uimenu(app.Filter);
            app.Median.MenuSelectedFcn = createCallbackFcn(app, @Median_Callback, true);
            app.Median.Text = 'Median';
            app.interpolate = uimenu(app.Filter);
            app.interpolate.MenuSelectedFcn = createCallbackFcn(app, @interpolate_Callback, true);
            app.interpolate.Text = 'Interpolate';
            app.Crop = uimenu(app.Data);
            app.Crop.MenuSelectedFcn = createCallbackFcn(app, @Crop_Callback, true);
            app.Crop.Text = 'Crop';
            app.axes1 = uiaxes(app.figure_gradpiv);
            app.axes1.XLim = [0.5 560.5];
            app.axes1.YLim = [0.5 420.5];
            app.axes1.YDir = 'reverse';
            app.axes1.Visible = 'off';
            app.pushbutton_select = uibutton(app.figure_gradpiv, 'push');
            app.pushbutton_select.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_select_Callback, true);
            app.pushbutton_select.Enable = 'off';
            app.pushbutton_select.Text = 'Select';
            app.pushbutton_spatial = uibutton(app.figure_gradpiv, 'push');
            app.pushbutton_spatial.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_spatial_Callback, true);
            app.pushbutton_spatial.Enable = 'off';
            app.pushbutton_spatial.Text = '> Spatial <';
            app.spatial_controls = uipanel(app.figure_gradpiv);
            app.spatial_controls.Title = 'Spatial';
            app.frame8 = uipanel(app.spatial_controls);
            app.frame8.Title = 'Quantity';
            app.checkbox_ensemble = uicheckbox(app.frame8);
            app.checkbox_ensemble.ValueChangedFcn = createCallbackFcn(app, @checkbox_ensemble_Callback, true);
            app.checkbox_ensemble.Text = 'Ensemble';
            app.checkbox_fluct = uicheckbox(app.frame8);
            app.checkbox_fluct.ValueChangedFcn = createCallbackFcn(app, @checkbox_fluct_Callback, true);
            app.checkbox_fluct.Text = 'Fluctuations';
            app.arrow_controls = uipanel(app.spatial_controls);
            app.arrow_controls.Title = 'Arrows';
            app.checkbox_arrow = uicheckbox(app.arrow_controls);
            app.checkbox_arrow.ValueChangedFcn = createCallbackFcn(app, @checkbox_arrow_Callback, true);
            app.checkbox_arrow.Text = 'On/Off';
            app.checkbox_arrow.Value = true;
            app.checkbox_arrow_color = uicheckbox(app.arrow_controls);
            app.checkbox_arrow_color.ValueChangedFcn = createCallbackFcn(app, @checkbox_arrow_color_Callback, true);
            app.checkbox_arrow_color.Enable = 'off';
            app.checkbox_arrow_color.Text = 'Color/Black';
            app.edit_arrow_size = uieditfield(app.arrow_controls, 'text');
            app.edit_arrow_size.ValueChangedFcn = createCallbackFcn(app, @edit_arrow_size_Callback, true);
            app.edit_arrow_size.Value = '1';
            app.text_arrow_size = uilabel(app.arrow_controls);
            app.text_arrow_size.Text = 'Arrow size';
            app.contour_quantity_panel = uipanel(app.spatial_controls);
            app.contour_quantity_panel.Title = 'Contour quantity';
            app.popupmenu_quantity = uidropdown(app.contour_quantity_panel);
            app.popupmenu_quantity.Items = {'-'};
            app.popupmenu_quantity.ValueChangedFcn = createCallbackFcn(app, @popupmenu_quantity_Callback, true);
            app.popupmenu_quantity.Value = '-';
            app.contour_panel = uipanel(app.spatial_controls);
            app.contour_panel.Title = 'Contour property';
            app.text_numberofcolors = uilabel(app.contour_panel);
            app.text_numberofcolors.Text = 'Number of colors';
            app.edit_numcolors = uieditfield(app.contour_panel, 'text');
            app.edit_numcolors.ValueChangedFcn = createCallbackFcn(app, @edit_numcolors_Callback, true);
            app.edit_numcolors.Value = '10';
            app.edit_min_clim = uieditfield(app.contour_panel, 'text');
            app.edit_min_clim.ValueChangedFcn = createCallbackFcn(app, @edit_min_clim_Callback, true);
            app.edit_min_clim.Visible = 'off';
            app.edit_max_clim = uieditfield(app.contour_panel, 'text');
            app.edit_max_clim.ValueChangedFcn = createCallbackFcn(app, @edit_max_clim_Callback, true);
            app.edit_max_clim.Visible = 'off';
            app.checkbox_colorbar = uicheckbox(app.contour_panel);
            app.checkbox_colorbar.ValueChangedFcn = createCallbackFcn(app, @checkbox_colorbar_Callback, true);
            app.checkbox_colorbar.Text = 'Colorbar';
            app.popupmenu_eachfield = uidropdown(app.contour_panel);
            app.popupmenu_eachfield.Items = {'Each Field', 'All to Display', 'All Fields', 'Manual'};
            app.popupmenu_eachfield.ValueChangedFcn = createCallbackFcn(app, @popupmenu_eachfield_Callback, true);
            app.popupmenu_eachfield.Value = 'Each Field';
            app.pushbutton_set_clim = uibutton(app.contour_panel, 'push');
            app.pushbutton_set_clim.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_set_clim_Callback, true);
            app.pushbutton_set_clim.Visible = 'off';
            app.pushbutton_set_clim.Text = 'Set';
            app.popupmenu_contour_type = uidropdown(app.contour_panel);
            app.popupmenu_contour_type.Items = {'None', 'Flood', 'Color Line', 'Flood+Line', 'Black Line'};
            app.popupmenu_contour_type.ValueChangedFcn = createCallbackFcn(app, @update_gui, true);
            app.popupmenu_contour_type.Value = 'None';
            app.checkbox_label = uicheckbox(app.contour_panel);
            app.checkbox_label.ValueChangedFcn = createCallbackFcn(app, @checkbox_label_Callback, true);
            app.checkbox_label.Enable = 'off';
            app.checkbox_label.Text = 'Label';
            app.flow_field_panel = uipanel(app.spatial_controls);
            app.flow_field_panel.Title = 'Flow field';
            app.pushbutton_previous = uibutton(app.flow_field_panel, 'push');
            app.pushbutton_previous.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_previous_Callback, true);
            app.pushbutton_previous.Text = 'Previous';
            app.pushbutton_next = uibutton(app.flow_field_panel, 'push');
            app.pushbutton_next.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_next_Callback, true);
            app.pushbutton_next.Text = 'Next';
            app.edit_current = uieditfield(app.flow_field_panel, 'text');
            app.edit_current.ValueChangedFcn = createCallbackFcn(app, @edit_current_Callback, true);
            app.edit_current.Value = 'N/A';
            app.text5 = uilabel(app.flow_field_panel);
            app.text5.Text = 'OF';
            app.edit_numfields = uieditfield(app.flow_field_panel, 'text');
            app.edit_numfields.Enable = 'off';
            app.edit_numfields.Value = 'N/A';
            app.pushbutton_animate = uibutton(app.flow_field_panel, 'state');
            app.pushbutton_animate.ValueChangedFcn = createCallbackFcn(app, @pushbutton_animate_Callback, true);
            app.pushbutton_animate.Text = 'Animate';
            app.pushbutton_save_movie = uibutton(app.flow_field_panel, 'state');
            app.pushbutton_save_movie.ValueChangedFcn = createCallbackFcn(app, @pushbutton_save_movie_Callback, true);
            app.pushbutton_save_movie.Text = 'Movie';
            app.select_controls = uipanel(app.figure_gradpiv);
            app.select_controls.Title = 'Select';
            app.select_controls.Visible = 'off';
            app.uipanel12 = uipanel(app.select_controls);
            app.uipanel12.Title = 'Links';
            app.pushbutton_profile1 = uibutton(app.uipanel12, 'push');
            app.pushbutton_profile1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_profile1_Callback, true);
            app.pushbutton_profile1.Text = 'Profile >>';
            app.pushbutton_time = uibutton(app.uipanel12, 'push');
            app.pushbutton_time.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_time_Callback, true);
            app.pushbutton_time.Text = 'Time Analysis';
            app.select_optons_panel = uipanel(app.select_controls);
            app.select_optons_panel.Title = 'Select options';
            app.pushbutton_selectall = uibutton(app.select_optons_panel, 'push');
            app.pushbutton_selectall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectall_Callback, true);
            app.pushbutton_selectall.Text = 'Select All';
            app.colpushbutton = uibutton(app.select_optons_panel, 'push');
            app.colpushbutton.ButtonPushedFcn = createCallbackFcn(app, @colpushbutton_Callback, true);
            app.colpushbutton.Text = 'Select/Add Row';
            app.pushbutton_selectreg = uibutton(app.select_optons_panel, 'push');
            app.pushbutton_selectreg.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectreg_Callback, true);
            app.pushbutton_selectreg.Text = 'Select/Add Region';
            app.pushbutton_selectpoints = uibutton(app.select_optons_panel, 'push');
            app.pushbutton_selectpoints.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectpoints_Callback, true);
            app.pushbutton_selectpoints.Text = 'Select/Add Points';
            app.rowpushbutton = uibutton(app.select_optons_panel, 'push');
            app.rowpushbutton.ButtonPushedFcn = createCallbackFcn(app, @rowpushbutton_Callback, true);
            app.rowpushbutton.Text = 'Select/Add Column';
            app.pushbutton_reset = uibutton(app.select_optons_panel, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_reset_Callback, true);
            app.pushbutton_reset.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = spatialbox_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_gradpiv)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_gradpiv)
        end
    end
end